classdef calibration_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CalibrationUIFigure  matlab.ui.Figure
        CalibrationSlider    matlab.ui.control.Slider
        PlayButton           matlab.ui.control.Button
        NextButton           matlab.ui.control.Button
        Instructions2        matlab.ui.control.Label
        Title                matlab.ui.control.Label
    end

    %
    % Code for this is loosely based from the ITD Localisation App by Lauren Ward
    %
    
    properties (Access = private)
        ILD             % Current value of the ILD in use
        Fs              % Current Fs for the noise signal
        currAudio       % Current audio (noise with variable level)
        earFlag         % Whether the ear is left or right
        burst           % Single upsampled noise burst
        refILD          % Reference -20dB difference
        iter            % total number of calibration trials to do
        counter         % Current iteration
        player          % audio player
        measuredILDs    % Array to store results
        mainApp         % Variable to hold the main app handle
        rightILD        % Right ILD
        leftILD         % Left ILD
    end
    
    methods (Access = private)
        
        function generateStimuli(app)
            
            % 1. Covert to log
            scaledValue = -10-(((100 - app.CalibrationSlider.Value)/10)*2);
            ildScale = (10^(scaledValue/10));
            app.ILD = 10*log10(ildScale);
            app.refILD = 0.01; % represents a -20dB Diff
            
            % Load & Scale Stimuli to correct ear
            
            [sigsiz, ~ ] = size(app.burst);
            signalIld = zeros(sigsiz,2);
            signalIldRef = zeros(sigsiz,2);
            
            
            if app.earFlag == 1 % right ear
                signalIld(1:sigsiz,1) = ildScale * app.burst(:,1);
                signalIld(1:sigsiz,2) = app.burst(:,2);
                
                signalIldRef(1:sigsiz,1) = app.refILD * app.burst(:,1);
                signalIldRef(1:sigsiz,2) = app.burst(:,2);
            else % left ear
                signalIld(1:sigsiz,1) = app.burst(:,1);
                signalIld(1:sigsiz,2) = ildScale * app.burst(:,2);
                
                signalIldRef(1:sigsiz,1) = app.burst(:,1);
                signalIldRef(1:sigsiz,2) = app.refILD * app.burst(:,2);
            end
            
            % 3. Play back
            
            %downsample
            temp1 = resample(signalIld, app.Fs, app.Fs*10); % Convert back to original sample rate
            temp2 = resample(signalIldRef, app.Fs, app.Fs*10); % Convert back to original sample rate
            
            app.currAudio = [temp1; temp2; temp1; temp2; temp1; temp2];
            
            app.player = audioplayer(app.currAudio, app.Fs);
            
        end
        
        function closeWindow(app,~,event)
            if event.SelectedOption == "Exit"
                calibrationComplete(app.mainApp,0)
                delete(app);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            %%%% THIS LINE IS ESSENTIAL!! IF IT IS NOT THERE MATLAB WILL
            %%%% CRACK THE SHITS AND GIVE YOU ERRORS ABOUT NOT TAKING
            %%%% 'DOUBLE' AS INPUTS IN THE FUNCTIONS YOU TRY TO PASS DATA TO
            
            % Need to add in calling app as in input variable
            app.mainApp = parentApp;
            
            app.CalibrationUIFigure.Visible = 'off';
            movegui(app.CalibrationUIFigure, 'center')
            app.CalibrationUIFigure.Visible = 'on';

            writeToCSV(app.mainApp,'Calibration');
            writeToCSV(app.mainApp,["Ear" "value in dB"]);

            % Number of trials to do (2 each ear)
            app.iter = 4;
            app.rightILD = 0;
            app.leftILD = 0;
            
            app.measuredILDs = zeros(app.iter, 1);
            app.counter = 1;

            % Set ear to right (=1) initially - this will alternate
            app.earFlag = 1;
            
            % Load target signal from file
            [temp, app.Fs] = audioread('shortburst.wav');
            
            % upsample the burst that is stored
            app.burst = resample(temp, app.Fs*10, app.Fs);
            
            % Generate first stimuli
            generateStimuli(app)
            
            % prime ready to play
            app.player = audioplayer(app.currAudio, app.Fs);
            
            app.PlayButton.Enable = true;
        end

        % Value changed function: CalibrationSlider
        function CalibrationSliderValueChanged(app, event)
            app.NextButton.Enable = true;
            
            generateStimuli(app);
            
            play(app.player);
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % If it is the last trial, go through the completion procedure
            app.NextButton.Enable = false;
            
            app.CalibrationSlider.Value = 0;
            
            app.measuredILDs(app.counter) = app.ILD;
            
            if app.earFlag == 1
                data = ["Right" app.ILD];
                writeToCSV(app.mainApp,data);
                app.rightILD = app.rightILD + app.ILD;
            else
                data = ["Left" app.ILD];
                writeToCSV(app.mainApp,data);
                app.leftILD = app.leftILD + app.ILD;
            end

            if app.counter == app.iter
                leftILDAverage = app.leftILD / (app.iter / 2);
                rightILDAverage = app.rightILD / (app.iter / 2);

                f = waitbar(0,'Please wait...');
                pause(.5)
                
                waitbar(.33,f,'Saving your data');
                data = ["Left-Average" leftILDAverage];
                writeToCSV(app.mainApp,data);
                data = ["Right-Average" rightILDAverage];
                writeToCSV(app.mainApp,data);
                
                waitbar(.67,f,'Generating data set');
                createScaledSofa(app.mainApp,leftILDAverage,rightILDAverage);
                waitbar(1,f,'Finishing');
                pause(1)
                close(f);

                calibrationComplete(app.mainApp,1)

                % Set completed and close app
                delete(app);
            else % otherwise set up for the next trials
                % Increment trial counter
                app.counter = app.counter + 1;
                
                % Swap ears
                if app.earFlag == 1
                    app.earFlag = 0;
                else
                    app.earFlag = 1;
                end
                
                generateStimuli(app);
                app.player = audioplayer(app.currAudio, app.Fs);
                
                %If it is the final trial, change the text to finish
                if app.counter == app.iter
                    app.NextButton.Text = 'Finish';
                end
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            stop(app.player)
            play(app.player)
        end

        % Key press function: CalibrationUIFigure
        function CalibrationUIFigureKeyPress(app, event)
            key = event.Key;
            value = app.CalibrationSlider.Value; % get the slider value
            
            if strcmp(key,'leftarrow')
                value = value-1; % left value
            elseif strcmp(key,'rightarrow')
                value = value+1; % right value
            end
            app.CalibrationSlider.Value = value; % set the slider value
            CalibrationSliderValueChanged(app, event); % execute the slider callback
        end

        % Close request function: CalibrationUIFigure
        function CalibrationUIFigureCloseRequest(app, event)
            
            fig = app.CalibrationUIFigure;
            temptext = ['Exiting the application will terminate all progress made so far.', newline, ...
                'RETURN to continue your progress or EXIT to terminate calibration.'];
            
            uiconfirm(fig,temptext,'Are you sure?',...
                'CloseFcn',@(src,event)closeWindow(app,src,event),...
                'Options',{'Return','Exit'},...
                'DefaultOption',2,'CancelOption',1,'Icon',"warning");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CalibrationUIFigure = uifigure('Visible', 'off');
            app.CalibrationUIFigure.Name = 'Calibration';
            app.CalibrationUIFigure.CloseRequestFcn = createCallbackFcn(app, @CalibrationUIFigureCloseRequest, true);
            app.CalibrationUIFigure.KeyPressFcn = createCallbackFcn(app, @CalibrationUIFigureKeyPress, true);
            app.CalibrationSlider = uislider(app.CalibrationUIFigure);
            app.CalibrationSlider.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.CalibrationSlider.MajorTickLabels = {'0', '10', '20', '30', '40', '50', '60', '70', '80', '90', '100'};
            app.CalibrationSlider.ValueChangedFcn = createCallbackFcn(app, @CalibrationSliderValueChanged, true);
            app.PlayButton = uibutton(app.CalibrationUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.NextButton = uibutton(app.CalibrationUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Text = 'Next';
            app.Instructions2 = uilabel(app.CalibrationUIFigure);
            app.Instructions2.Text = {'Increase the slider, listening to the noise bursts.'; ''; 'Initially the noise bursts will appear to come from the same source location'; ''; 'Keep increasing it until the noise bursts appear'; 'to alternate between different source locations '; ''; 'Sounds will appear all on the left side or all on the right side. '};
            app.Title = uilabel(app.CalibrationUIFigure);
            app.Title.Text = 'Calibration ';
            % Show the figure after all components are created
            app.CalibrationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibration_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CalibrationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CalibrationUIFigure)
        end
    end
end