classdef experiment_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ListeningTestUIFigure        matlab.ui.Figure
        headImg                      matlab.ui.control.Image
        positionSlider               matlab.ui.control.Slider
        NextButton                   matlab.ui.control.Button
        PreviousButton               matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        UsingtheslidermovethextothelocationyouLabel  matlab.ui.control.Label
        perceivethesoundtobeatLabel  matlab.ui.control.Label
        Trail1of10Label              matlab.ui.control.Label
        MoveXwiththeSliderLabel      matlab.ui.control.Label
        PartLabel                    matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp             % parent application
        trainingFlag        % training
        hrtfSofa            % hrtf data for scaled sofa
        hrtfLookUp          % positions of the IR
        hrtfGenericLookUp   % generic positions
        hrtfGeneric         % generic HRTF
        positions           % positions to test
        positionIndexes     % randomised indexes of positions for the trials
        trials              % number of trials
        iteration           % iteration number
        player              % audio player
        Fs                  % sample rate
        currAudio           % current Audio to be played
        polarAxis           % polarAxis
        experimentLocations % locaitons and HRTF dataset for the experiment
    end
    
    methods (Access = private)
        
        function runTraining(app)
            app.PartLabel.Visible = false;
            app.positions = [0 45 90 -45 -90];
            app.trials = size(app.positions,2);
            app.positionIndexes = randperm(app.trials );
            app.iteration = 1;
            updateTrialMessage(app);
            writeToCSV(app.mainApp, "Training");
            runTrial(app);
        end
        
        function runTrial(app)
            % get position
            azimuth = app.positions(app.positionIndexes(app.iteration));
            
            [rawAudio, app.Fs] = audioread("shortburst.wav");
            
            audio = convolveAudio(app,azimuth,rawAudio,1);
            app.currAudio = [audio; audio; audio; audio];
            % prime ready to play
            app.player = audioplayer(app.currAudio, app.Fs);
            play(app.player);
            app.PlayButton.Enable = true;
        end
        
        function runExperiment(app)
            app.PartLabel.Visible = true;
            app.experimentLocations = readmatrix('csv/evaluation-positions.csv');
            app.positions = app.experimentLocations(:,1)';
            app.trials = size(app.positions,2);
            app.positionIndexes = randperm(app.trials);
            app.iteration = 1;
            updateTrialMessage(app);
            writeToCSV(app.mainApp, "Evaluation");
            runTrial(app);
        end
        
        function index = getIRIndex(app,azimuth,scaled)
            % returns the index of the IR from the inputted azimuth
            if scaled
                hrtfpositions = app.hrtfLookUp;
                index = hrtfpositions(hrtfpositions == azimuth,2);
            else
                hrtfpositions = app.hrtfGenericLookUp;
                index = hrtfpositions(hrtfpositions == azimuth,2);
            end
            
        end
        
        function audio = convolveAudio(app, azimuth, soundInput, scaled)
            % returns audio
            if scaled
                index = getIRIndex(app,azimuth,scaled);
                audio = [conv(squeeze(app.hrtfSofa.Data.IR(index, 2, :)), soundInput(:,1)) ...
                    conv(squeeze(app.hrtfSofa.Data.IR(index, 1, :)), soundInput(:,1))];
            else
                index = getIRIndex(app,azimuth,scaled);
                audio = [conv(squeeze(app.hrtfGeneric.Data.IR(index, 2, :)), soundInput(:,1)) ...
                    conv(squeeze(app.hrtfGeneric.Data.IR(index, 1, :)), soundInput(:,1))];
            end
        end
        
        %% Update polar plot
        function updateplot(app, value)
            % Plot x on polar plot - input is in degrees and needs to be
            % negated and converted to radians to plot correctly
            polarplot(app.polarAxis, deg2rad(-value), 1.5, 'xr', ...
                'MarkerSize', 15, 'LineWidth', 3 );
            
            % Set plotting parameters
            app.polarAxis.RLim = [0 2]; % set up plot limits
            app.polarAxis.RTickLabel = {};
            app.polarAxis.ThetaZeroLocation = 'top';
            app.polarAxis.ThetaTickLabel = {'0', '-30', '-60', '-90', ...
                '-120', '-150', [char(177) '180'], '150', '120', '90', ...
                '60', '30'};
            % Char177 is the plus or minus symbol
        end
        
        function closeWindow(app,~,event)
            if event.SelectedOption == "Exit"
                if app.trainingFlag
                    trainingComplete(app.mainApp,0)
                else
                    listeningTestComplete(app.mainApp, 0)
                end
                delete(app);
            end
        end
        
        function updateTrialMessage(app)
            if app.trainingFlag
                trialText = sprintf("Trial %i of %i", app.iteration, app.trials);
                app.Trail1of10Label.Text = trialText;
            elseif app.iteration <= app.trials/2
                partText = sprintf("Part %i of %i", 1, 2);
                app.PartLabel.Text = partText;
                trialText = sprintf("Trial %i of %i", app.iteration, app.trials/2);
                app.Trail1of10Label.Text = trialText;
            else
                partText = sprintf("Part %i of %i", 2, 2);
                app.PartLabel.Text = partText;
                trialText = sprintf("Trial %i of %i", app.iteration - app.trials/2, app.trials/2);
                app.Trail1of10Label.Text = trialText;
            end
        end
        
        function displayTrainingMessages(app)
            fig = app.ListeningTestUIFigure;
            if app.iteration == 2
                uialert(fig,['Clicking next saves your results and ' ...
                    'advances onto the next trial.'], ...
                    'Training Info','Icon','info', 'CloseFcn', ...
                    @(src,event)resumeTest(app,src,event));
            elseif app.iteration == 3
                uialert(fig,['You can play the sound as many ' ...
                    'times as you want.'], ...
                    'Training Info','Icon','info', 'CloseFcn', ...
                    @(src,event)resumeTest(app,src,event));
            elseif app.iteration == 4
                uialert(fig,['If you are unsure about where you hear ' ...
                    'the sound, go with your initial instinct.'], ...
                    'Training Info','Icon','info', 'CloseFcn', ...
                    @(src,event)resumeTest(app,src,event));
            elseif app.iteration == 5
                uialert(fig,['It is normal to perceive some sounds inside ' ...
                    'your head - If you hear the sound inside your head, ' ...
                    'indicate the location you hear it with reference to ' ...
                    'the very centre of your head.'], ...
                    'Training Info','Icon','info', 'CloseFcn', ...
                    @(src,event)resumeTest(app,src,event));
            end
        end
        
        function resumeTest(app,~,~)
            updateTrialMessage(app);
            runTrial(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp, training)
            app.ListeningTestUIFigure.Visible = 'off';
            movegui(app.ListeningTestUIFigure, 'center')
            app.ListeningTestUIFigure.Visible = 'on';
            
            app.mainApp = parentApp;
            app.hrtfSofa = getScaledSofa(app.mainApp);
            app.hrtfLookUp = getSofaLookUp(app.mainApp);
            app.hrtfGeneric = getGenericSofa(app.mainApp);
            app.hrtfGenericLookUp = getGenericSofaLookUp(app.mainApp);
            app.trainingFlag = training;
            
            % Create polar axes and position it in pixels
            app.polarAxis = polaraxes(app.ListeningTestUIFigure);
            app.polarAxis.Units = 'pixels';
            positionx = app.headImg.Position(1)-65;
            positiony = app.headImg.Position(2)-70;
            app.polarAxis.Position = [positionx positiony 230 230];
            updateplot(app, 0);
            app.PartLabel.Visible = false;
            
            pause(2);
            
            if training
                runTraining(app)
            else
                runExperiment(app)
            end
        end

        % Close request function: ListeningTestUIFigure
        function ListeningTestUIFigureCloseRequest(app, event)
            fig = app.ListeningTestUIFigure;
            temptext = ['Exiting the application will terminate ' ...
                'all progress made so far.', newline, ...
                'RETURN to continue your progress or EXIT to ' ...
                'terminate the listening test.'];
            
            uiconfirm(fig,temptext,'Are you sure?',...
                'CloseFcn',@(src,event)closeWindow(app,src,event),...
                'Options',{'Return','Exit'},...
                'DefaultOption',2,'CancelOption',1,'Icon',"warning");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            app.NextButton.Enable = false;
            updateplot(app, 0);
            if app.trainingFlag
                % Store the values in the csv
                estimatedPosition = app.positionSlider.Value;
                actualPosition = app.positions(app.positionIndexes(app.iteration)) ;
                scaled = 1;
                data = [app.iteration estimatedPosition actualPosition scaled];
                writeToCSV(app.mainApp,data);
                app.positionSlider.Value = 0;
                
                % If it is the last trial, go through the completion procedure
                if app.iteration == app.trials
                    trainingComplete(app.mainApp,1)
                    % Set completed and close app
                    delete(app);
                else % otherwise set up for the next trials
                    % Increment trial counter
                    app.iteration = app.iteration + 1;
                    displayTrainingMessages(app);
                    %If it is the final trial, change the text to finish
                    if app.iteration == app.trials
                        app.NextButton.Text = 'Finish';
                    end
                end
            else
                % Store the values in the csv
                estimatedPosition = app.positionSlider.Value;
                actualPosition = app.positions(app.positionIndexes(app.iteration)) ;
                scaled = app.experimentLocations(app.positionIndexes(app.iteration),2);
                data = [app.iteration estimatedPosition actualPosition scaled];
                writeToCSV(app.mainApp,data);
                
                app.NextButton.Enable = false;
                app.positionSlider.Value = 0;
                % If it is the last trial, go through the completion procedure
                if app.iteration == app.trials
                    % Set completed and close app
                    listeningTestComplete(app.mainApp,1)
                    delete(app);
                elseif app.iteration == app.trials/2
                    app.iteration = app.iteration + 1;
                    fig = app.ListeningTestUIFigure;
                    uialert(fig,['You reached the half way point.' ...
                        ' Please take a short break before resuming.'], ...
                        'Take a Break','Icon','info', 'CloseFcn', ...
                        @(src,event)resumeTest(app,src,event));
                else % otherwise set up for the next trials
                    % Increment trial counter
                    app.iteration = app.iteration + 1;
                    updateTrialMessage(app);
                    runTrial(app)
                    %If it is the final trial, change the text to finish
                    if app.iteration == app.trials
                        app.NextButton.Text = 'Finish';
                    end
                end
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButtonPushed(app, event)
            % This button is hidden and not in use.
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            stop(app.player)
            play(app.player)
        end

        % Value changed function: positionSlider
        function positionSliderValueChanged(app, event)
            value = app.positionSlider.Value;
            updateplot(app, value);
            app.NextButton.Enable = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ListeningTestUIFigure = uifigure('Visible', 'off');
            app.ListeningTestUIFigure.Name = 'Listening Test';
            app.ListeningTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @ListeningTestUIFigureCloseRequest, true);
            app.headImg = uiimage(app.ListeningTestUIFigure);
            app.headImg.Tooltip = {'using the slider move the x to the location you percive the sound to be at'};
            app.headImg.ImageSource = 'head.svg';
            app.positionSlider = uislider(app.ListeningTestUIFigure);
            app.positionSlider.Limits = [-180 180];
            app.positionSlider.MajorTicks = [-180 -170 -160 -150 -140 -130 -120 -110 -100 -90 -80 -70 -60 -50 -40 -30 -20 -10 0 10 20 30 40 50 60 70 80 90 100 110 120 130 140 150 160 170 180];
            app.positionSlider.MajorTickLabels = {'-180', '', '', '-150', '', '', '-120', '', '', '-90', '', '', '-60', '', '', '-30', '', '', '0', '', '', '30', '', '', '60', '', '', '90', '', '', '120', '', '', '150', '', '', '180', ''};
            app.positionSlider.ValueChangedFcn = createCallbackFcn(app, @positionSliderValueChanged, true);
            app.positionSlider.MinorTicks = [-180 -178 -176 -174 -172 -170 -168 -166 -164 -162 -160 -158 -156 -154 -152 -150 -148 -146 -144 -142 -140 -138 -136 -134 -132 -130 -128 -126 -124 -122];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
            app.NextButton = uibutton(app.ListeningTestUIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Enable = 'off';
            app.NextButton.Tooltip = {'next trial'};
            app.NextButton.Text = 'Next';
            app.PreviousButton = uibutton(app.ListeningTestUIFigure, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.PreviousButton.Enable = 'off';
            app.PreviousButton.Visible = 'off';
            app.PreviousButton.Tooltip = {'previous trial'};
            app.PreviousButton.Text = 'Previous';
            app.PlayButton = uibutton(app.ListeningTestUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Tooltip = {'play trial'};
            app.PlayButton.Text = 'Play';
            app.UsingtheslidermovethextothelocationyouLabel = uilabel(app.ListeningTestUIFigure);
            app.UsingtheslidermovethextothelocationyouLabel.Text = {'Using the slider, move the x to the location you'; ''};
            app.perceivethesoundtobeatLabel = uilabel(app.ListeningTestUIFigure);
            app.perceivethesoundtobeatLabel.Text = 'perceive the sound to be at ';
            app.Trail1of10Label = uilabel(app.ListeningTestUIFigure);
            app.Trail1of10Label.Text = 'Trail 1 of 10';
            app.MoveXwiththeSliderLabel = uilabel(app.ListeningTestUIFigure);
            app.MoveXwiththeSliderLabel.Text = 'Move X with the Slider';
            app.PartLabel = uilabel(app.ListeningTestUIFigure);
            app.PartLabel.Text = 'Part x of y';
            % Show the figure after all components are created
            app.ListeningTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ListeningTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ListeningTestUIFigure)
        end
    end
end