classdef experiment_settings_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ExperimentSettingsUIFigure  matlab.ui.Figure
        ExperimentSettingsLabel     matlab.ui.control.Label
        ConfirmandSetSaveLocationButton  matlab.ui.control.Button
        Enterthelast3digitsofyourpostcodeEditFieldLabel  matlab.ui.control.Label
        postcodeNums                matlab.ui.control.EditField
        Enterthelast4digitsofyourphonenumberEditFieldLabel  matlab.ui.control.Label
        phoneNums                   matlab.ui.control.EditField
        GenerateyouUniqueIdentifierLabel  matlab.ui.control.Label
        ResetFormButton             matlab.ui.control.Button
        CancelButton                matlab.ui.control.Button
        UIDText                     matlab.ui.control.Label
        UIDInput                    matlab.ui.control.Label
        SetAudioLevelLabel          matlab.ui.control.Label
        VolumeSettingInstructions   matlab.ui.control.Label
        PlayButton                  matlab.ui.control.Button
        generalError                matlab.ui.control.Label
        postCodeError               matlab.ui.control.Label
        phoneError                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp     % parent application
        UID         % unique id
        playFlag    % determine if the calibration noise has been played
        player      % player to play the audio sample
    end
    
    methods (Access = private)
        
        function validated = validateUID(app)
            % returns true or false depending on if the UID is valid
            if (size(app.postcodeNums.Value,2) == 3 && size(app.phoneNums.Value,2) == 4)
                app.postCodeError.Visible = false;
                app.phoneError.Visible = false;
                app.generalError.Visible = false;
                
                validated = true;
            else
                validated = false;
                if size(app.postcodeNums.Value,2) ~= 3 && size(app.phoneNums.Value,2) ~= 4
                    app.postCodeError.Visible = true;
                    app.phoneError.Visible = true;
                    app.generalError.Visible = true;
                elseif size(app.postcodeNums.Value,2) ~= 3
                    app.postCodeError.Visible = true;
                    app.phoneError.Visible = false;
                    app.generalError.Visible = true;
                elseif size(app.phoneNums.Value,2) ~= 4
                    app.postCodeError.Visible = false;
                    app.phoneError.Visible = true;
                    app.generalError.Visible = true;
                end
            end
        end
        
        function generateUID(app, postCode, phone)
            app.UID = string(postCode) + string(phone);
            app.UIDInput.Text = app.UID;
        end
        
        function enableConfirm(app)
            if validateUID(app) && app.playFlag
                app.ConfirmandSetSaveLocationButton.Enable = true;
            else
                app.ConfirmandSetSaveLocationButton.Enable = false;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            app.ExperimentSettingsUIFigure.Visible = 'off';
            movegui(app.ExperimentSettingsUIFigure, 'center')
            app.ExperimentSettingsUIFigure.Visible = 'on';
            
            app.mainApp = parentApp;
            app.postCodeError.Visible = false;
            app.phoneError.Visible = false;
            app.generalError.Visible = false;
            if isstring(getUID(app.mainApp))
                tempUID = getUID(app.mainApp);
                mainPostCode = extractBetween(tempUID,1,3);
                mainPhone = extractBetween(tempUID,4,7);
                app.postcodeNums.Value = mainPostCode;
                app.phoneNums.Value = mainPhone;
                generateUID(app,mainPostCode,mainPhone);
                app.playFlag = 1;
                enableConfirm(app);
            else
                enableConfirm(app);
                app.ConfirmandSetSaveLocationButton.Enable = false;
                app.playFlag = 0;
            end
        end

        % Close request function: ExperimentSettingsUIFigure
        function ExperimentSettingsUIFigureCloseRequest(app, event)
            experimentSettingsCompletedOrClosed(app.mainApp, 0)
            delete(app)
        end

        % Button pushed function: ConfirmandSetSaveLocationButton
        function ConfirmandSetSaveLocationButtonPushed(app, event)
            try
                stop(app.player);
            catch
            end
            
            if isempty(getUID(app.mainApp)) || app.UID ~= getUID(app.mainApp)
                dt = datestr(now,'ddmmyyHHMMSS');
                csvName = app.UID + "_" + dt + ".csv";
                [fileName, path] = uiputfile(csvName);
                csvLocation = fullfile(path,fileName);
                setCSVLocation(app.mainApp,csvLocation);
                try
                    writeToCSV(app.mainApp,app.UID)
                catch
                    % not a file
                end
                setUID(app.mainApp, app.UID)
                experimentSettingsCompletedOrClosed(app.mainApp, 1)
            end
            
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            experimentSettingsCompletedOrClosed(app.mainApp, 0)
            delete(app)
        end

        % Button pushed function: ResetFormButton
        function ResetFormButtonPushed(app, event)
            app.postcodeNums.Value = "";
            app.phoneNums.Value = "";
            app.UIDInput.Text = "-------";
            app.playFlag = 0;
            stop(app.player);
            enableConfirm(app);
        end

        % Value changed function: postcodeNums
        function postcodeNumsValueChanged(app, event)
            postcode = app.postcodeNums.Value;
            phone = app.phoneNums.Value;
            generateUID(app,postcode,phone);
            enableConfirm(app);
        end

        % Value changed function: phoneNums
        function phoneNumsValueChanged(app, event)
            postcode = app.postcodeNums.Value;
            phone = app.phoneNums.Value;
            generateUID(app,postcode,phone);
            enableConfirm(app);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            % Play audio - currently temp audio
            fig = app.ExperimentSettingsUIFigure;
            [y, Fs] = audioread('PinkNoise_1minCal.wav');
            app.player = audioplayer(y, Fs);
            uialert(fig,['Check that your headphones are on the correct ' ...
                'way round.'], ...
                'Check your Headphones!','Icon','info',"CloseFcn", ...
                @(src,event)play(app.player));
            app.playFlag = 1;
            enableConfirm(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ExperimentSettingsUIFigure = uifigure('Visible', 'off');
            app.ExperimentSettingsUIFigure.Name = 'Experiment Settings';
            app.ExperimentSettingsUIFigure.CloseRequestFcn = createCallbackFcn(app, @ExperimentSettingsUIFigureCloseRequest, true);
            app.ExperimentSettingsLabel = uilabel(app.ExperimentSettingsUIFigure);
            app.ExperimentSettingsLabel.Text = 'Experiment Settings';
            app.ConfirmandSetSaveLocationButton = uibutton(app.ExperimentSettingsUIFigure, 'push');
            app.ConfirmandSetSaveLocationButton.ButtonPushedFcn = createCallbackFcn(app, @ConfirmandSetSaveLocationButtonPushed, true);
            app.ConfirmandSetSaveLocationButton.Text = 'Confirm and Set Save Location';
            app.Enterthelast3digitsofyourpostcodeEditFieldLabel = uilabel(app.ExperimentSettingsUIFigure);
            app.Enterthelast3digitsofyourpostcodeEditFieldLabel.Text = 'Enter the last 3 digits of your post code';
            app.postcodeNums = uieditfield(app.ExperimentSettingsUIFigure, 'text');
            app.postcodeNums.ValueChangedFcn = createCallbackFcn(app, @postcodeNumsValueChanged, true);
            app.postcodeNums.Tooltip = {'enter the last 3 digits to generate Unique ID'};
            app.Enterthelast4digitsofyourphonenumberEditFieldLabel = uilabel(app.ExperimentSettingsUIFigure);
            app.Enterthelast4digitsofyourphonenumberEditFieldLabel.Text = 'Enter the last 4 digits of your phone number';
            app.phoneNums = uieditfield(app.ExperimentSettingsUIFigure, 'text');
            app.phoneNums.ValueChangedFcn = createCallbackFcn(app, @phoneNumsValueChanged, true);
            app.phoneNums.Tooltip = {'enter the last 4 digits to generate Unique ID'};
            app.GenerateyouUniqueIdentifierLabel = uilabel(app.ExperimentSettingsUIFigure);
            app.GenerateyouUniqueIdentifierLabel.Text = '1. Generate you Unique Identifier';
            app.ResetFormButton = uibutton(app.ExperimentSettingsUIFigure, 'push');
            app.ResetFormButton.ButtonPushedFcn = createCallbackFcn(app, @ResetFormButtonPushed, true);
            app.ResetFormButton.Text = 'Reset Form';
            app.CancelButton = uibutton(app.ExperimentSettingsUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.UIDText = uilabel(app.ExperimentSettingsUIFigure);
            app.UIDText.Text = {'Your unique ID is '; ''; 'If you request a copy of your data, or for your data to be deleted, '; 'we will ask for the above information to identify you. '};
            app.UIDInput = uilabel(app.ExperimentSettingsUIFigure);
            app.UIDInput.Text = '------';
            app.SetAudioLevelLabel = uilabel(app.ExperimentSettingsUIFigure);
            app.SetAudioLevelLabel.Text = '2. Set Audio Level';
            app.VolumeSettingInstructions = uilabel(app.ExperimentSettingsUIFigure);
            app.VolumeSettingInstructions.Text = {'Press play and using the calibration tone, '; 'set your headphones to a comfortable listening level.'; ''; 'TURN AUDIO DOWN BEFORE PRESSING PLAY'};
            app.PlayButton = uibutton(app.ExperimentSettingsUIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.generalError = uilabel(app.ExperimentSettingsUIFigure);
            app.generalError.Text = 'There are errors with your submission.';
            app.postCodeError = uilabel(app.ExperimentSettingsUIFigure);
            app.postCodeError.Text = 'Postcode is not 3 digits';
            app.phoneError = uilabel(app.ExperimentSettingsUIFigure);
            app.phoneError.Text = 'Phone Number is not 4 digits';
            % Show the figure after all components are created
            app.ExperimentSettingsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = experiment_settings_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ExperimentSettingsUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ExperimentSettingsUIFigure)
        end
    end
end