classdef info_sheet_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InfomationSheetUIFigure  matlab.ui.Figure
        AgreeButton              matlab.ui.control.Button
        DisagreeButton           matlab.ui.control.Button
        HTML                     matlab.ui.control.HTML
        IAgreeLabel              matlab.ui.control.Label
        InfomationSheetLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        mainApp % mainApp that calls the function
    end
    
    methods (Access = private)
        
        function closeWindow(app,~,event)
            if event.SelectedOption == "Exit"
                infoSheetConsentButtonPressed(app.mainApp, 0)
                delete(app);
            end 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parentApp)
            %%%%THIS LINE IS ESSENTIAL!! IF IT IS NOT THERE MATLAB WILL
            %%%%CRACK THE SHITS AND GIVE YOU ERRORS ABOUT NOT TAKING
            %%%%'DOUBLE' AS INPUTS IN THE FUNCTIONS YOU TRY TO PASS DATA TO
            app.mainApp = parentApp;
            
            app.InfomationSheetUIFigure.Visible = 'off';
            movegui(app.InfomationSheetUIFigure, 'center')
            app.InfomationSheetUIFigure.Visible = 'on';
        end

        % Button pushed function: AgreeButton
        function AgreeButtonPushed(app, event)
            % Function if participant agrees with the information sheet
            % Set flag to 1 - agree
            sheetCompleted = 1;
            
            % Call function from main app to update flag in main app
            infoSheetConsentButtonPressed(app.mainApp, sheetCompleted)
            
            % Exit current app
            delete(app)
        end

        % Button pushed function: DisagreeButton
        function DisagreeButtonPushed(app, event)
            % Function if participant disagrees with the information sheet
            % Set flag to 1 - agree
            sheetCompleted = 0;
            
            % Call function from main app to update flag in main app
            infoSheetConsentButtonPressed(app.mainApp, sheetCompleted)
            
            % Exit current app
            delete(app)
        end

        % Close request function: InfomationSheetUIFigure
        function InfomationSheetUIFigureCloseRequest(app, event)
            fig = app.InfomationSheetUIFigure;
            temptext = ['You have not selected an option.', newline, ...
                'Would you like to RETURN to agree,'  newline, ...
                'or disagree and EXIT the experiment?'];
            
            uiconfirm(fig,temptext,'Confirm Close',...
                'CloseFcn',@(src,event)closeWindow(app,src,event),...
                'Options',{'Return','Exit'},...
                'DefaultOption',2,'CancelOption',1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InfomationSheetUIFigure = uifigure('Visible', 'off');
            app.InfomationSheetUIFigure.Name = 'Infomation Sheet';
            app.InfomationSheetUIFigure.CloseRequestFcn = createCallbackFcn(app, @InfomationSheetUIFigureCloseRequest, true);
            app.AgreeButton = uibutton(app.InfomationSheetUIFigure, 'push');
            app.AgreeButton.ButtonPushedFcn = createCallbackFcn(app, @AgreeButtonPushed, true);
            app.AgreeButton.Text = 'Agree';
            app.DisagreeButton = uibutton(app.InfomationSheetUIFigure, 'push');
            app.DisagreeButton.ButtonPushedFcn = createCallbackFcn(app, @DisagreeButtonPushed, true);
            app.DisagreeButton.Text = 'Disagree';
            app.HTML = uihtml(app.InfomationSheetUIFigure);
            app.HTML.HTMLSource = '<!DOCTYPE html><html><body style="font-family:arial;font-size: 13px;">    <p>        This study is investigating audio algorithms designed to improve how individuals localise where a sound comes        from        when using binaural technology.        We are inviting participants who are: </p>    <ul>        <li>            normal hearing        </li>        <li>            18 years or older        </li>    </ul>    <p> to participate in this study. You may terminate your participation at any time. </p>    <h2>What tasks will I be asked to complete?</h2>    <p>        This App has five parts that you will be guided through. These are:    <ul>        <li>Infomation Sheet - Reading and indicating if you agree or disagree to participate.</li>        <li>Experiment Settings - Generating a unique ID and setting your headphones to a comfortable level.</li>        <li>Calibration - Complete a short process to generate a unique audio algorithm.</li>        <li>Training - to introduce different parts of the interface. </li>        <li>Listening Test - The full listening test, this involves short noise bursts and identifying on a diagram            the direction you perceived them as coming from. </li>    </ul>    <p>After completing the listening test you will need to upload the results.</p>    <h2>Are there any risks involved?</h2>    <p>        Participation in this study is low risk, with noise exposure and fatigue the only possible risks. To mitigate        the risk        of noise exposure, the app will guide you through the process setting the audio level to a safe volume before being allowed to continue.</p>    <p>        To minimise the risk of fatigue, the app will prompt you to take a break part way through.         <b>            You are reminded your            participation in this experiment is voluntary and you may pause the experiment to take a rest or stop the experiment            entirely at any time.        </b>    </p>   <p>    By completing the tasks in this app you will be contributing to research at the University of York. This means that    you    consent to us using your responses for the research purposes outlined below. All data is collected anonymously and    is    stored confidentially in accordance with UK and EU Data Protection laws.   </p>    <p>    Anonymous responses to the survey and experimental task will be stored indefinitely. They may be used (either    individually or aggregated across participants) in internal or external research publications and/or to inform the    design and implementation of new audio technology with our commercial partners.   </p>      <em>Results from the app will only be stored if you choose to upload them.</em>    <p>    If you wish to receive a copy of your data or withdraw your data, you can do this by contacting    <a href="mailto:ncab500@york.ac.uk">ncab500@york.ac.uk</a>    or <a href="mailto:gavin.kearney@york.ac.uk">gavin.kearney@york.ac.uk</a></p>    </body></html>';
            app.IAgreeLabel = uilabel(app.InfomationSheetUIFigure);
            app.IAgreeLabel.Text = 'I have read the infomation above and I ';
            app.InfomationSheetLabel = uilabel(app.InfomationSheetUIFigure);
            app.InfomationSheetLabel.Text = 'Infomation Sheet';
            % Show the figure after all components are created
            app.InfomationSheetUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = info_sheet_app(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InfomationSheetUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InfomationSheetUIFigure)
        end
    end
end