classdef main_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ILDListeningTestUIFigure  matlab.ui.Figure
        InfomationSheetButton     matlab.ui.control.Button
        ExperimentSettingsButton  matlab.ui.control.Button
        CalibrationButton         matlab.ui.control.Button
        TrainingButton            matlab.ui.control.Button
        ListeningTestButton       matlab.ui.control.Button
        ListeningTestLabel        matlab.ui.control.Label
        InfoSheetImg              matlab.ui.control.Image
        ExperimentSettingsImg     matlab.ui.control.Image
        CalibrationImg            matlab.ui.control.Image
        TrainingImg               matlab.ui.control.Image
        ListeningTestImg          matlab.ui.control.Image
        infoLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        infoSheetFlag               % 1 if sheet completed 0 otherwise
        experimentSettingsFlag      % 1 if settings completed 0 otherwise
        calibrationFlag             % 1 if calibration completed 0 otherwise
        trainingFlag                % 1 if training completed 0 otherwise
        listeningTestFlag           % 1 if listeningTest completed 0 otherwise
        csvLocation                 % Location of the CSV for storing results
        UID                         % unique idenfitier
        scaledSOFA                  % scaled SOFA File
        genericSOFA                 % KU100 SOFA
        genericSofaPositionsLookUp  % KU100 look-up
        sofaPositionsLookUp         % scaled lookup
        completed                   % when the listening test is completed == 1
    end


    methods (Access = public)
        
        %% Info Sheet Functions
        function infoSheetConsentButtonPressed(app, completed)
            if completed == 1
                app.infoSheetFlag = completed;
                app.InfoSheetImg.ImageSource = 'success.svg';
                app.ExperimentSettingsButton.Enable = true;
            elseif completed == 0
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not agreed to participate in the study.' ...
                    ' To continue re-open the infomation sheet and click ' ...
                    'agree otherwise exit the applicaition.'], ...
                    'No consent given','Icon','warning');
                
                % Set all buttons to false and fail icons
                app.InfoSheetImg.ImageSource = 'fail.svg';
                app.ExperimentSettingsImg.ImageSource = 'fail.svg';
                app.CalibrationImg.ImageSource = 'fail.svg';
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                
                app.ExperimentSettingsButton.Enable = false;
                app.CalibrationButton.Enable = false;
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = false;
            else
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,'Unknown Error occured, please try again.', ...
                    'Error','Icon','error');
            end
        end
        
        
        %% Experiment Settings Functions
        
        function experimentSettingsCompletedOrClosed(app, completed)
            if completed == 1 && isfile(app.csvLocation)
                app.experimentSettingsFlag = completed;
                app.ExperimentSettingsImg.ImageSource = 'success.svg';
                app.CalibrationImg.ImageSource = 'fail.svg';
                app.CalibrationButton.Enable = true;
                
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = false;
                
            elseif completed == 1 && ~isfile(app.csvLocation)
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not completed the experiment settings.' ...
                    ' To continue re-open and complete the experiment settings'], ...
                    'Settings not complete','Icon','error');
                
                % Set all buttons to false and fail icons
                app.ExperimentSettingsImg.ImageSource = 'fail.svg';
                app.CalibrationImg.ImageSource = 'fail.svg';
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                
                app.CalibrationButton.Enable = false;
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = false;
            elseif completed == 0 && app.experimentSettingsFlag ~= 1
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not completed the experiment settings.' ...
                    ' To continue re-open and complete the experiment settings'], ...
                    'Settings not complete','Icon','error');
                
                % Set all buttons to false and fail icons
                app.ExperimentSettingsImg.ImageSource = 'fail.svg';
                app.CalibrationImg.ImageSource = 'fail.svg';
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                
                app.CalibrationButton.Enable = false;
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = false;
            elseif completed == 0 && app.experimentSettingsFlag == 1
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['Setting remain unchanged, to change or update the' ...
                    ' save file location please complete the settings again.'], ...
                    'Setting not Updated','Icon','warning');
            else
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,'Unknown Error occured, please try again.', ...
                    'Error','Icon','error');
            end
        end
        
        function setCSVLocation(app, location)
            app.csvLocation = location;
        end
        
        function setUID(app, UID)
            app.UID = UID;
        end
        
        function UID = getUID(app)
            UID = app.UID;
        end
        
        %% Calibration Settings
        function calibrationComplete(app, completed)
            if completed == 1
                app.calibrationFlag = completed;
                app.CalibrationImg.ImageSource = 'success.svg';
                app.CalibrationButton.Enable = false;
                app.TrainingButton.Enable = true;
            elseif completed == 0 && app.calibrationFlag ~= 1
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not completed calibration.' ...
                    ' To continue complete the calibration.'], ...
                    'Calibration incomplete','Icon','warning');
                
                % Set all buttons to false and fail icons
                app.CalibrationImg.ImageSource = 'fail.svg';
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = false;
            else
                % we should never reach this error
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,'Unknown Error occured, please try again.', ...
                    'Error','Icon','error');
            end
        end
        
        
        %% General Settings
        function csvLocation = getCSVLocation(app)
            csvLocation = app.csvLocation;
        end
        
        function writeToCSV(app, data)
            % append infomation to CSV files
            writematrix(data, app.csvLocation, ...
                'Delimiter',',', ...
                "FileType",'text', ...
                'WriteMode', 'append', ...
                'QuoteStrings',true);
        end
        
        function createScaledSofa(app, ildLeftEstimate, ildRightEstimate)
            % Estimated ILD from measurement
            ILDLeftEstimated = ildLeftEstimate;
            ILDRightEstimated = ildRightEstimate;
            
            SOFAstart();
            
            % Load in Generic KU100 HRTF
            % hrtf = SOFAload('D1_44K_16bit_256tap_FIR_SOFA.sofa');

            % Load 48K to match the 48K of the Audio Source
            hrtf = SOFAload('sofa/D1_48K_24bit_256tap_FIR_SOFA.sofa');
            hrtfExpanded = SOFAexpand(hrtf);
            
            % Create Array for HRTF along 0 Elevation
            ILD = zeros(1,360);
            
            % store left and right channels
            leftChannels = hrtfExpanded.SourcePosition(:,2,:);
            rightChannels = hrtfExpanded.SourcePosition(:,1,:);
            
            posIndex = 1;
            
            % Calculate the ILD of all the positions
            while posIndex < size(rightChannels,1)
                posIndex = posIndex + 1;
                elevationPos = leftChannels(posIndex);
                azimuthPos = rightChannels(posIndex);
                if elevationPos == 0
                    HRTF_R = norm(squeeze(hrtfExpanded.Data.IR(posIndex,1,:)));
                    HRTF_L = norm(squeeze(hrtfExpanded.Data.IR(posIndex,2,:)));
                    ILD(round(azimuthPos+1)) = 10*log(HRTF_L/HRTF_R);
                end
            end
            
            ILDLeftGeneric = ILD(90);
            ILDRightGeneric = -ILD(270);
            
            % Calculate Scaling Factor
            scalingFactorLeft = ILDLeftEstimated / ILDLeftGeneric;
            scalingFactorRight = ILDRightEstimated / ILDRightGeneric;
            
            % Scale and Resave to SOFA for efficency
            hrtfScaled = hrtf;
            
            % 1 - 180 = Left Ear
            % 181 - 360 = Right Ear
            
            % Create Array for HRTF along 0 Elevation
            ILDCalculated = zeros(1,360);
            
            % Calculate ILD for all the positions
            leftChannels = hrtfExpanded.SourcePosition(:,2,:);
            rightChannels = hrtfExpanded.SourcePosition(:,1,:);
            
            posIndex = 1;
            
            % generate new scaled ILDs from scaling factor
            while posIndex < size(rightChannels,1)
                posIndex = posIndex + 1;
                elevationPos = leftChannels(posIndex);
                azimuthPos = rightChannels(posIndex);
                if elevationPos == 0
                    if azimuthPos <= 180
                        leftData = hrtfExpanded.Data.IR(posIndex,1,:).^scalingFactorLeft;
                        rightData = hrtfExpanded.Data.IR(posIndex,2,:).^scalingFactorLeft;
                        hrtfScaled.Data.IR(posIndex,1,:) = real(leftData);
                        hrtfScaled.Data.IR(posIndex,2,:) = real(rightData);
                        HRTF_RTest = norm(squeeze(hrtfScaled.Data.IR(posIndex,1,:)));
                        HRTF_LTest = norm(squeeze(hrtfScaled.Data.IR(posIndex,2,:)));
                    elseif azimuthPos > 180
                        leftData = hrtfExpanded.Data.IR(posIndex,1,:).^scalingFactorRight;
                        rightData = hrtfExpanded.Data.IR(posIndex,2,:).^scalingFactorRight;
                        hrtfScaled.Data.IR(posIndex,1,:) = real(leftData);
                        hrtfScaled.Data.IR(posIndex,2,:) = real(rightData);
                        HRTF_RTest = norm(squeeze(hrtfScaled.Data.IR(posIndex,1,:)));
                        HRTF_LTest = norm(squeeze(hrtfScaled.Data.IR(posIndex,2,:)));
                    end
                    ILDCalculated(round(azimuthPos+1)) = 10*log(HRTF_LTest/HRTF_RTest);
                end
            end
            
            % update comments and global info
            hrtfScaled.GLOBAL_Title = 'ILD Scaled D1 KU100 subject';
            hrtfScaled.GLOBAL_ListenerShortName = 'D1-Scaled';
            hrtfScaled.GLOBAL_Comment = ['Only positions along 0 ' ...
                'elevation are scaled. 8802 source positions. KU100' ...
                '  subject. Measurement utlized Genelec 8010 Loudspeakers' ...
                '  and 24s overlapped swept sine technique. Reciever ' ...
                'microphones were KU100 built in mics via RME Fireface' ...
                '  400 Preamps. Filters were low frequency extended, ' ...
                'diffuse field equalized and windowed. (approx.) ' ...
                'Linear phase HRIRs.'];
            
            % save to variable and generate lookup tables
            app.scaledSOFA = hrtfScaled;
            generateSofaPositionsLookUp(app);
            
            app.genericSOFA = hrtfExpanded;
            generateGenericSofaPositionsLookUp(app);
        end
        
        % Training and Listening Test Functions
        function trainingComplete(app,completed)
            if completed == 1
                app.trainingFlag = completed;
                app.TrainingImg.ImageSource = 'success.svg';
                app.TrainingButton.Enable = false;
                app.ListeningTestButton.Enable = true;
            elseif completed == 0
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not completed training.' ...
                    ' To continue complete the training.'], ...
                    'Training incomplete','Icon','warning');
                
                % Set all buttons to false and fail icons
                app.TrainingImg.ImageSource = 'fail.svg';
                app.ListeningTestImg.ImageSource = 'fail.svg';
                
                app.ListeningTestButton.Enable = false;
            else
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,'Unknown Error occured, please try again.', ...
                    'Error','Icon','error');
            end
        end
        function listeningTestComplete(app,completed)
            if completed == 1
                app.listeningTestFlag = completed;
                app.ListeningTestImg.ImageSource = 'success.svg';
                app.ListeningTestButton.Enable = false;
                fig = app.ILDListeningTestUIFigure;
                text = sprintf(['Congratulations! You have now finished the' ...
                    ' listening test please upload your results.\n\n ' ...
                    'Results are saved at: \n%s\n\n It is now safe to ' ...
                    'close the window.'],app.csvLocation);
                uialert(fig,text,'Listening Test Complete','Icon','success');
                app.completed = 1;
            elseif completed == 0
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,['You have not completed the listening test.' ...
                    ' To continue complete the listening test.'], ...
                    'Listening Test incomplete','Icon','warning');
                
                % Set all buttons to false and fail icons
                app.ListeningTestImg.ImageSource = 'fail.svg';
            else
                fig = app.ILDListeningTestUIFigure;
                uialert(fig,'Unknown Error occured, please try again.', ...
                    'Error','Icon','error');
            end
        end
        
        
        function sofa = getScaledSofa(app)
            sofa = app.scaledSOFA;
        end
        function lookup = getSofaLookUp(app)
            lookup = app.sofaPositionsLookUp;
        end
        
        function sofa = getGenericSofa(app)
            sofa = app.genericSOFA;
        end
        function lookup = getGenericSofaLookUp(app)
            lookup = app.genericSofaPositionsLookUp;
        end
    end


    methods (Access = private)
        
        function closeWindow(app,~,event)
            if event.SelectedOption == "Exit"
                delete(app);
            end
        end
        
        function generateSofaPositionsLookUp(app)
            hrtf = app.scaledSOFA;
            apparentSourceVector = SOFAcalculateAPV(hrtf);
            rows = size(apparentSourceVector(:,1),1);
            
            posIndex = 1;
            arrayIndex = 1;
            sofaPositions = nan(rows,2);
            
            while posIndex < rows
                posIndex = posIndex + 1;
                elevationPos = apparentSourceVector(posIndex,2);
                azimuthPos = apparentSourceVector(posIndex,1);
                if elevationPos == 0
                    sofaPositions(arrayIndex,:) = [azimuthPos, posIndex];
                    arrayIndex= arrayIndex + 1;
                end
            end
            
            app.sofaPositionsLookUp = sofaPositions;
        end
        
        
        function generateGenericSofaPositionsLookUp(app)
            hrtf = app.genericSOFA;
            apparentSourceVector = SOFAcalculateAPV(hrtf);
            rows = size(apparentSourceVector(:,1),1);
            
            posIndex = 1;
            arrayIndex = 1;
            sofaPositions = nan(rows,2);
            
            while posIndex < rows
                posIndex = posIndex + 1;
                elevationPos = apparentSourceVector(posIndex,2);
                azimuthPos = apparentSourceVector(posIndex,1);
                if elevationPos == 0
                    sofaPositions(arrayIndex,:) = [azimuthPos, posIndex];
                    arrayIndex= arrayIndex + 1;
                end
            end
            
            app.genericSofaPositionsLookUp = sofaPositions;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InfoSheetImg.ImageSource = 'fail.svg';
            
            app.ILDListeningTestUIFigure.Visible = 'off';
            movegui(app.ILDListeningTestUIFigure, 'center')
            app.ILDListeningTestUIFigure.Visible = 'on';
            
            app.infoSheetFlag = 0;
            app.experimentSettingsFlag = 0;
            app.calibrationFlag = 0;
            app.trainingFlag = 0;
            app.listeningTestFlag = 0;
        end

        % Button pushed function: InfomationSheetButton
        function InfomationSheetButtonPushed(app, event)
            info_sheet_app(app);
        end

        % Button pushed function: ExperimentSettingsButton
        function ExperimentSettingsButtonPushed(app, event)
            experiment_settings_app(app);
        end

        % Close request function: ILDListeningTestUIFigure
        function ILDListeningTestUIFigureCloseRequest(app, event)
            if app.completed ~= 1
            fig = app.ILDListeningTestUIFigure;
            temptext = ['Exiting the application will terminate all progress made so far.', newline, ...
                'RETURN to continue your progress or EXIT to terminate participation.'];
            
            uiconfirm(fig,temptext,'Are you sure?',...
                'CloseFcn',@(src,event)closeWindow(app,src,event),...
                'Options',{'Return','Exit'},...
                'DefaultOption',2,'CancelOption',1,'Icon',"warning");
            else
                delete(app);
            end
        end

        % Button pushed function: CalibrationButton
        function CalibrationButtonPushed(app, event)
            calibration_app(app);
        end

        % Button pushed function: TrainingButton
        function TrainingButtonPushed(app, event)
            experiment_app(app,1);
        end

        % Button pushed function: ListeningTestButton
        function ListeningTestButtonPushed(app, event)
            experiment_app(app,0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ILDListeningTestUIFigure = uifigure('Visible', 'off');
            app.ILDListeningTestUIFigure.Name = 'ILD Listening Test';
            app.ILDListeningTestUIFigure.CloseRequestFcn = createCallbackFcn(app, @ILDListeningTestUIFigureCloseRequest, true);
            app.InfomationSheetButton = uibutton(app.ILDListeningTestUIFigure, 'push');
            app.InfomationSheetButton.ButtonPushedFcn = createCallbackFcn(app, @InfomationSheetButtonPushed, true);
            app.InfomationSheetButton.Text = 'Infomation Sheet';
            app.ExperimentSettingsButton = uibutton(app.ILDListeningTestUIFigure, 'push');
            app.ExperimentSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @ExperimentSettingsButtonPushed, true);
            app.ExperimentSettingsButton.Enable = 'off';
            app.ExperimentSettingsButton.Text = 'Experiment Settings';
            app.CalibrationButton = uibutton(app.ILDListeningTestUIFigure, 'push');
            app.CalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrationButtonPushed, true);
            app.CalibrationButton.Enable = 'off';
            app.CalibrationButton.Text = 'Calibration';
            app.TrainingButton = uibutton(app.ILDListeningTestUIFigure, 'push');
            app.TrainingButton.ButtonPushedFcn = createCallbackFcn(app, @TrainingButtonPushed, true);
            app.TrainingButton.Enable = 'off';
            app.TrainingButton.Text = 'Training';
            app.ListeningTestButton = uibutton(app.ILDListeningTestUIFigure, 'push');
            app.ListeningTestButton.ButtonPushedFcn = createCallbackFcn(app, @ListeningTestButtonPushed, true);
            app.ListeningTestButton.Enable = 'off';
            app.ListeningTestButton.Text = 'Listening Test ';
            app.ListeningTestLabel = uilabel(app.ILDListeningTestUIFigure);
            app.ListeningTestLabel.Text = 'Listening Test';
            app.InfoSheetImg = uiimage(app.ILDListeningTestUIFigure);
            app.InfoSheetImg.ImageSource = 'success.svg';
            app.ExperimentSettingsImg = uiimage(app.ILDListeningTestUIFigure);
            app.ExperimentSettingsImg.ImageSource = 'fail.svg';
            app.CalibrationImg = uiimage(app.ILDListeningTestUIFigure);
            app.CalibrationImg.ImageSource = 'fail.svg';
            app.TrainingImg = uiimage(app.ILDListeningTestUIFigure);
            app.TrainingImg.ImageSource = 'fail.svg';
            app.ListeningTestImg = uiimage(app.ILDListeningTestUIFigure);
            app.ListeningTestImg.ImageSource = 'fail.svg';
            app.infoLabel = uilabel(app.ILDListeningTestUIFigure);
            app.infoLabel.Text = 'Click the buttons in order to work through the listening test.';
            % Show the figure after all components are created
            app.ILDListeningTestUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ILDListeningTestUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ILDListeningTestUIFigure)
        end
    end
end